classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MoveinYdirSpinner               matlab.ui.control.Spinner
        MoveinYdirSpinnerLabel          matlab.ui.control.Label
        MoveinXdirSpinner               matlab.ui.control.Spinner
        MoveinXdirSpinnerLabel          matlab.ui.control.Label
        MoveinZdirSpinner               matlab.ui.control.Spinner
        MoveinZdirSpinnerLabel          matlab.ui.control.Label
        PrepareforviusalservoingButton  matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        MovekittyButton                 matlab.ui.control.Button
        SHOOTRIGHTButton                matlab.ui.control.Button
        SHOOTLEFTButton                 matlab.ui.control.Button
        MOVERPYButton                   matlab.ui.control.Button
        CURRENTZmEditField              matlab.ui.control.NumericEditField
        CURRENTZmEditFieldLabel         matlab.ui.control.Label
        CURRENTYmEditField              matlab.ui.control.NumericEditField
        CURRENTYmEditFieldLabel         matlab.ui.control.Label
        CURRENTXmEditField              matlab.ui.control.NumericEditField
        CURRENTXmEditFieldLabel         matlab.ui.control.Label
        ClickSHOOTtomakethemovementLabel  matlab.ui.control.Label
        SHOOTMIDButton                  matlab.ui.control.Button
        EButtonStateLamp                matlab.ui.control.Lamp
        EButtonStateLampLabel           matlab.ui.control.Label
        NOButton                        matlab.ui.control.Button
        RELEASEESTOPLabel               matlab.ui.control.Label
        YESButton                       matlab.ui.control.Button
        DefaultButton_2                 matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SHOOTINGPOSITIONLabel           matlab.ui.control.Label
        PlayingmodeLabel                matlab.ui.control.Label
        RightcourtButton                matlab.ui.control.Button
        MiddlecourtButton               matlab.ui.control.Button
        LeftcourtButton                 matlab.ui.control.Button
        AngleEditField                  matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        InitialvelocitymsEditField      matlab.ui.control.NumericEditField
        InitialvelocitymsEditFieldLabel  matlab.ui.control.Label
        LoadmodeltoCMDTestButton        matlab.ui.control.Button
        HOMEButton                      matlab.ui.control.StateButton
        q7Spinner                       matlab.ui.control.Spinner
        q7SpinnerLabel                  matlab.ui.control.Label
        q6Spinner                       matlab.ui.control.Spinner
        q6SpinnerLabel                  matlab.ui.control.Label
        q5Spinner                       matlab.ui.control.Spinner
        q5SpinnerLabel                  matlab.ui.control.Label
        q4Spinner                       matlab.ui.control.Spinner
        q4SpinnerLabel                  matlab.ui.control.Label
        q3Spinner                       matlab.ui.control.Spinner
        q3SpinnerLabel                  matlab.ui.control.Label
        q2Spinner                       matlab.ui.control.Spinner
        q2SpinnerLabel                  matlab.ui.control.Label
        q1mSpinner                      matlab.ui.control.Spinner
        q1mSpinnerLabel                 matlab.ui.control.Label
        ZmEditField                     matlab.ui.control.NumericEditField
        ZmEditFieldLabel                matlab.ui.control.Label
        YmEditField                     matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.NumericEditField
        XmEditFieldLabel                matlab.ui.control.Label
        YawSlider                       matlab.ui.control.Slider
        YawSliderLabel                  matlab.ui.control.Label
        PitchSlider                     matlab.ui.control.Slider
        PitchSliderLabel                matlab.ui.control.Label
        RollSlider                      matlab.ui.control.Slider
        RollSliderLabel                 matlab.ui.control.Label
        MovetoXYZpostionLabel           matlab.ui.control.Label
        Button                          matlab.ui.control.StateButton
        MoveButton                      matlab.ui.control.StateButton
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Environment
        robot = RacketBot(transl(-1,1.5,0.5));
        robot2 = DobotMagician(transl(0.5,0.9,1.5)*trotz(pi));
        bricknum


        vertexColours
        BrickMesh_h = PlotBird(0);
        BrickMesh_hLeft = PlotBird(-0.5);
        BrickMesh_hRight = PlotBird(1.5);
    

        kittyMesh_h = PlotCat;
        RacketMesh_h = PlotRacket;
        u = Utils();
        cam = CentralCamera('focal',0.08 , 'pixel', 10e-5, ...
            'resolution', [1000 1000], 'centre', round([1000/2 1000/2]), 'name', 'myCamera');
        %a = arduino("COM6","Uno");
    end
    
    properties (Access = private)

        counter = 0
        q0 = [0, 0, -pi/2, pi/3, pi/3, pi, 0];
        xPos double             
    
        counterFoward
        counterBackward
        v = cell2mat(struct2cell(load('v.mat')));
        BrickVertexCount double
        kittyVertexCount double
        vertex = cell2mat(struct2cell(load('vertex.mat')));
        racketVertex = cell2mat(struct2cell(load('racketVertex.mat')));
        num
        qHit 


        deltaT

        brick
        qTraj
     
              % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Value changed function: MoveButton
        function MoveButtonValueChanged(app, event)
            %value = app.MoveButton.Value;
            assignin("base","button",app.Button.Value)
            x = app.XmEditField.Value;
            y = app.YmEditField.Value;
            z = app.ZmEditField.Value;

            pos = [x y z];
            tr = transl(pos);

            qCurrent = app.robot.model.getpos();

            T = app.robot.model.fkineUTS(qCurrent);


            qEnd = app.robot.model.ikcon(tr);

            app.qTraj = jtraj(qCurrent,qEnd,100);
       
            app.counter = 0;
            for i = 1:100
                
                 
                q = app.qTraj(i,:);
                app.robot.model.animate(q);
                T = app.robot.model.fkineUTS(q);
                app.CURRENTXmEditField.Value = T(1,4);
                app.CURRENTYmEditField.Value = T(2,4);
                app.CURRENTZmEditField.Value = T(3,4);
                pause(0.01);
                app.counter = app.counter + 1;
                if app.Button.Value == 1
                    disp("Emergency Button: Triggerd!")
                    break
                    
                end
                disp("Emergency Button: Not triggered")
                app.Button.Value == 0;
              
            end

              
        end

        % Value changed function: Button
        function ButtonValueChanged2(app, event)
            %value = app.Button.Value;
            app.EButtonStateLamp.Color = 'r' ;
            assignin("base","EButton",app.Button.Value);
            
        end

        % Value changing function: q1mSpinner
        function q1mSpinnerValueChanging(app, event)
            changingValue = event.Value;            
            qCurrent = app.robot.model.getpos();
            q = [app.q1mSpinner.Value, qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);
        end

        % Value changing function: q2Spinner
        function q2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), deg2rad(app.q2Spinner.Value),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);
        end

        % Value changing function: q3Spinner
        function q3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2), deg2rad(app.q3Spinner.Value),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);
            
        end

        % Value changing function: q4Spinner
        function q4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),deg2rad(app.q4Spinner.Value),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);

        end

        % Value changing function: q5Spinner
        function q5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),deg2rad(app.q5Spinner.Value),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);            
            
        end

        % Value changing function: q6Spinner
        function q6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),deg2rad(app.q6Spinner.Value),qCurrent(1,7)];
            app.robot.model.animate(q);            
        end

        % Value changing function: q7Spinner
        function q7SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),deg2rad(app.q7Spinner.Value)];
            app.robot.model.animate(q);            
        end

        % Value changed function: HOMEButton
        function HOMEButtonValueChanged(app, event)
            if app.Button.Value == 0
            qCurrent = app.robot.model.getpos();
            qHome = zeros(1,7);
            qTrajHome = jtraj(qCurrent,qHome,50);
            for i = 1:50
                app.robot.model.animate(qTrajHome(i,:));
                pause(0.01);
            end
            end
        end

        % Button pushed function: LoadmodeltoCMDTestButton
        function LoadmodeltoCMDTestButtonPushed(app, event)
           
            assignin("base","r",app.robot);
            assignin("base","r2",app.robot2);
        end

        % Value changed function: InitialvelocitymsEditField
        function InitialvelocitymsEditFieldValueChanged(app, event)
            value = app.InitialvelocitymsEditField.Value;
            
        end

        % Button pushed function: LeftcourtButton
        function LeftcourtButtonPushed(app, event)
            app.xPos = -0.5;
            app.qHit = [0 deg2rad([0 0 0 -90 0 90])];
        end

        % Button pushed function: MiddlecourtButton
        function MiddlecourtButtonPushed(app, event)
            app.xPos = 0;
            app.qHit = [-0.75 deg2rad([0 0 0 -90 0 90])];
        end

        % Button pushed function: RightcourtButton
        function RightcourtButtonPushed(app, event)
            app.xPos = 1.25;
            app.qHit = [-1.25 deg2rad([0 0 0 0 0 0])];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin("base","velocity",app.InitialvelocitymsEditField.Value);
            assignin("base","angle",app.AngleEditField.Value);
            assignin("base","xPos",app.xPos);
            assignin("base","EButton",app.Button.Value);
            assignin("base","v",app.v);


        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.AngleEditField.Value = 60;
        end

        % Button pushed function: DefaultButton_2
        function DefaultButton_2Pushed(app, event)
            app.InitialvelocitymsEditField.Value =6;
        end

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            app.Button.Value = 0;
            app.EButtonStateLamp.Color = 'g' ;
            %writeDigitalPin(app.a, 'D6', 1); %blue
            %writeDigitalPin(app.a, 'D5', 0);    %red
            %assignin("base","EButton",app.Button.Value);
        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
            app.Button.Value = 0;
            app.EButtonStateLamp.Color = 'r' ;
        end

        % Button pushed function: SHOOTMIDButton
        function SHOOTMIDButtonPushed(app, event)
       
            app.BrickVertexCount = 2588

            qHit = [0 deg2rad([0 0 0 -90 0 90])];
            velocity = app.InitialvelocitymsEditField.Value;
            g = 9.81;
            theta = deg2rad(app.AngleEditField.Value);
            tEnd = 2*velocity*sin(theta)/g;
    
            t = linspace(0,tEnd);

            y = velocity*t*cos(theta)-1;
            z = 0.1+velocity*t*sin(theta)-0.5*g*t.^2;
            x = zeros(1,100);
   

            %% Find the contact point => num

            % tr = r.model.fkine(r.model.getpos).T;
            % dist = zeros(100,1);
            % for i = 1:100
            %     %x((1,i)-tr(1,4))^2
            %     %(z(1,i)-tr(3,4))^2
            %     dist(i,1) = sqrt(   (y(1,i)-tr(2,4))^2  ); 
            %     d_min = min(dist);
            % end
            % 
            % counter = 1;
            % for i = 1:100
            %     if dist(i,1) - d_min == 0
            %         num = counter;
            %     end
            %     counter = counter + 1;
            % end
            num = 65;
            
            for i = 1:num+6
                plot3(x(1,i),y(1,i),z(1,i),'.','MarkerSize',5,'Color','k');
                pause(0.01);         
            end
            
            %% Calculate the contact pose with the shuttlecock

            T1 = [eye(3) [x(1,num) y(1,num) z(1,num)-0.1]'; zeros(1,3) 1]; %Pose when contact the shuttle cock

            q1 = app.robot.model.ikcon(T1,qHit);                    % Solve for joint angles with initial joint guess


            %% Move to swing pose
            qNow = app.robot.model.getpos();
            qTraj = jtraj(qNow,q1,50);
            for i = 1:50
                app.robot.model.animate(qTraj(i,:));
                pause(0.01);
            end
            %% Swing pose by RMRC-Resolved motion rate control to get the wayPoint matrix --> qMatrix

            steps = num;

            x1 = [x(1,num) y(1,num) (z(1,num)-0.1) pi/3 0 0]';
            x2 = [x(1,num) y(1,num+6) (z(1,num+6)-0.1) 0 0 0]';
            deltaT = 0.05;                                        % Discrete time step

            xR = zeros(6,steps);

            s = lspb(0,1,steps);                                 % Create interpolation scalar with TRAPEZODIAL Profiles
            for i = 1:steps
                xR(:,i) = x1*(1-s(i)) + s(i)*x2;                 %Linear interpolation  

            end

            qMatrix = [nan(steps,6) zeros(steps,1)];

            qMatrix(1,:) = q1;

            for i = 1:steps-1
                xdot = (xR(:,i+1) - xR(:,i))/deltaT;
                J = app.robot.model.jacob0(qMatrix(i,:));       %non linear mapping of joint velocity to the endeffector velocity
                J = J(1:6,:);          
                qdot = pinv(J)*xdot;                             % Solve velocitities via RMRC with Pseudo inverse pinv() 
                qMatrix(i+1,:) =  qMatrix(i,:) + deltaT*qdot(:,1)';
            end

            %Take the rank(J) = 6 < 7 columns --> Redundant robot [rank(J) tells us how the robot to catergorize the robot]
            %What if:
            %rank(J) < m,n --> Singularities
            %rank(J) < n, m --> Undereactuated

            % J*transpose(J) also describes velocity ellipsoid 
            % --> How fast the EE can move in different directions

            %% Swing to hit the shuttlecock

            counter = num;
            for i=1:num
                %writeDigitalPin(app.a, 'D6', 1);
                %buttonValue = readDigitalPin(app.a,'D2');
                brick = transl(x(1,i),y(1,i),z(1,i))*trotx(i*pi/100);

                BrickPose = brick ;

                UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);

                app.robot.model.animate(qMatrix(counter,:));

                T = app.robot.model.fkine(qMatrix(counter,:)).T;
                plot3(T(1,4),T(2,4),T(3,4),'.','MarkerSize',5,'Color','r'); %Plot the points of the EE's trajectory 

                counter = counter - 1;

                pause(0.01);
                if app.Button.Value == 1 
                    brick = transl([app.xPos,-1.5,0.1]);

                    BrickPose = brick ;

                    UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                    app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);
                    disp("Emergency Button: Triggered!")
                    % writeDigitalPin(app.a, 'D6', 0);
                    % writeDigitalPin(app.a, 'D5', 1);
                    app.EButtonStateLamp.Color = 'r';
                    break
                end
                
            end
            %% Shuttlecock flying back and recoil motion of the robot

            if app.Button.Value == 0 
  
                counter = 1;
                for i=num:-1:1
                    %writeDigitalPin(app.a, 'D6', 1);
                    brick = transl(x(1,i),y(1,i),z(1,i))*trotx(i*pi/100);

                    BrickPose = brick ;

                    UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                    app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);


                    app.robot.model.animate(qMatrix(counter,:));

                    T = app.robot.model.fkine(qMatrix(counter,:)).T;            

                    counter = counter + 1;
                    if app.Button.Value == 1 
                        brick = transl([app.xPos,-1.5,0.1]);

                        BrickPose = brick ;

                        UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';
                    
                        app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);

                        disp("Emergency Button: Triggerd!")

                        %writeDigitalPin(app.a, 'D6', 0);
                        %writeDigitalPin(app.a, 'D5', 1);
                        break
                    end

                    pause(0.01);
                end
            end
            %% Move back to the initial pose
            if app.Button.Value == 0 
                qCurrent = app.robot.model.getpos();
                qTraj = jtraj(qCurrent,zeros(1,7),50);
                for i = 1:50
                    app.robot.model.animate(qTraj(i,:));
                    pause(0.01);
                end

            end

        end

        % Button pushed function: MOVERPYButton
        function MOVERPYButtonPushed(app, event)
            roll = deg2rad(app.RollSlider.Value);
            pitch = deg2rad(app.PitchSlider.Value);
            yaw = deg2rad(app.YawSlider.Value);
            T = rpy2tr(roll,pitch,yaw);
            q = app.robot.model.ikcon(T,zeros(1,7));
            app.robot.model.animate(q);

        end

        % Button pushed function: SHOOTLEFTButton
        function SHOOTLEFTButtonPushed(app, event)
  
            app.BrickVertexCount = 2588         %Manually give the brick vertex count equal 2588
            %% Predict the flying path of the shuttlecock with projectile motion 

            qHit = [0 deg2rad([0 0 0 -90 0 90])];  %Joint guess for hitting pose of the robot

            velocity = app.InitialvelocitymsEditField.Value;    %User's input velocity
            g = 9.81; 
            theta = deg2rad(app.AngleEditField.Value);          %User's input angle
            tEnd = 2*velocity*sin(theta)/g;

            t = linspace(0,tEnd);

            y = velocity*t*cos(theta)-1;                       %Projectile motion formula
            z = 0.1+velocity*t*sin(theta)-0.5*g*t.^2;
            x = ones(1,100)*(-0.2);                            %The shuttlecock shooting from the left side of the court


            %% Find the contact point => num
            % tr = r.model.fkine(r.model.getpos).T;
            % dist = zeros(100,1);
            % for i = 1:100
            %     %x((1,i)-tr(1,4))^2
            %     %(z(1,i)-tr(3,4))^2
            %     dist(i,1) = sqrt(   (y(1,i)-tr(2,4))^2  ); 
            %     d_min = min(dist);
            % end
            % 
            % counter = 1;
            % for i = 1:100
            %     if dist(i,1) - d_min == 0
            %         num = counter;
            %     end
            %     counter = counter + 1;
            % end
            num = 65;
            for i = 1:num
                predict = plot3(x(1,i),y(1,i),z(1,i),'.','MarkerSize',25,'Color','k');
                pause(0.01);
                delete(predict);
            end

            %% Calculate the pose for contact position with the shuttlecock

            T1 = [eye(3) [x(1,num) y(1,num) z(1,num)-0.1]'; zeros(1,3) 1]; %Pose when contact the shuttlecock      


            q1 = app.robot.model.ikcon(T1,qHit);                    % Solve for joint angles of the swing pose


            %% Move the end-effector to contact point with the shuttlecock
            qNow = [-0.1 0 0 0 0 0 0];
            qTraj = jtraj(qNow,q1,50);
            for i = 1:50
                app.robot.model.animate(qTraj(i,:));
                pause(0.01);
            end
            %% Swing pose generated by RMRC-Resolved motion rate control to create the qMatrix

            steps = num;

            x1 = [x(1,num) y(1,num) (z(1,num)-0.1) pi/3 0 0]';
            x2 = [x(1,num) y(1,num+6) (z(1,num+6)-0.1) 0 0 0]';
            deltaT = 0.05;                                        % Discrete time step

            xR = zeros(6,steps);

            s = lspb(0,1,steps);                                 % Create interpolation scalar
            for i = 1:steps
                xR(:,i) = x1*(1-s(i)) + s(i)*x2;

            end

            qMatrix = [nan(steps,6) zeros(steps,1)];

            qMatrix(1,:) = q1;


            for i = 1:steps-1
                xdot = (xR(:,i+1) - xR(:,i))/deltaT;
                J = app.robot.model.jacob0(qMatrix(i,:));
                J = J(1:6,:);
                qdot = pinv(J)*xdot;                             % Solve velocitities via RMRC
                qMatrix(i+1,:) =  qMatrix(i,:) + deltaT*qdot(:,1)';
            end
            %% Moving the cat forward

            app.kittyVertexCount = 450;

            %% Create the plane (light curtain)
            [Y,Z] = meshgrid(0:0.1:3,0:0.1:1.8);
            X = repmat(-2,size(Y,1),size(Y,2));
            surf(X,Y,Z,'FaceAlpha',0.1,'EdgeColor','r');
            planeNormal = [-5,1,0];  % A point on the normal vector (perpendicular to the plane)
            planePoint = [-2,1,0];   % A point on the plane
            checkResult = zeros(100,1);

            %%
            steps = 0.0125;   %steps = (endPoint - startPoint)/num 

            counter = num;
            for i=1:num

                brick = transl(x(1,i),y(1,i),z(1,i))*trotx(i*pi/100);

                BrickPose = brick ;

                UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                app.BrickMesh_hLeft.Vertices = UpdatedPoints(:,1:3);


                app.robot.model.animate(qMatrix(counter,:));

                % LineStartPoint is at the tail of the cat             
                lineStartPoint = [-3.25+steps*i,1,0];   %Increase the start point by "step" amount

                % LineEndPoint is at the paw of the cat
                lineEndPoint = [-2.75+steps*i,1,0];     % Increase the end point by "steps" amount

                lineMidPoint = [-3+steps*i,1,0];        % Increase the mid point by "steps" amount

                kittyPose = transl(lineMidPoint)*trotz(pi);
                UpdatedPoints = [kittyPose * [app.vertex,ones(app.kittyVertexCount,1)]']';

                app.kittyMesh_h.Vertices = UpdatedPoints(:,1:3);

                [intersectionPoints,check] = LinePlaneIntersection(planeNormal,planePoint,lineStartPoint,lineEndPoint); % IMPORTANT LINE %

                checkResult(i,1) = check;


                counter = counter - 1;              

                pause(0.01);
                if check == 1
                    brick = transl([app.xPos,-1.5,0.1]);

                    BrickPose = brick ;

                    UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                    app.BrickMesh_hLeft.Vertices = UpdatedPoints(:,1:3);
                    disp("COLLISION HAPPEN!")
                    app.Button.Value = 1
                    app.EButtonStateLamp.Color = 'r'
                    break
                    
                end
                if app.Button.Value == 1
                    brick = transl([app.xPos,-1.5,0.1]);

                    BrickPose = brick ;

                    UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                    app.BrickMesh_hLeft.Vertices = UpdatedPoints(:,1:3);
                    disp("Emergency Button: Triggered!")
                    break
                end

            end
            
            %% Generate the return flying path for the shuttlecock
            % Assume this is the projectile motion with no air resistance 
            angleReturn = -40;
            velocityReturn = 5;
            thetaReturn = deg2rad(angleReturn);
            tEndReturn = 2*velocity*sin(thetaReturn)/g;         
            tReturn = linspace(0,tEndReturn);
            yReturn = velocityReturn*tReturn*cos(thetaReturn)+y(65);
            zReturn = z(65)+velocityReturn*tReturn*sin(thetaReturn)-0.5*g*tReturn.^2;
            xReturn = -0.5*ones(1,100);

            %% 
            %counter = 1;
            if app.Button.Value == 0
              
                for i=1:100
                    brick2 = transl(xReturn(1,i),yReturn(1,i),zReturn(1,i))*trotx(i*pi/100);

                    BrickPose = brick2 ;

                    UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                    app.BrickMesh_hLeft.Vertices = UpdatedPoints(:,1:3);


                    app.robot.model.animate(qMatrix(1,:));

                    %T = app.robot.model.fkine(qMatrix(i,:)).T;

                    %counter = counter + 1;
                    if app.Button.Value== 1
                        brick = transl([app.xPos,-1.5,0.1]);

                        BrickPose = brick ;

                        UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                        app.BrickMesh_hLeft.Vertices = UpdatedPoints(:,1:3);

                        disp("Emergency Button: Triggerd!")
                        break
                    end

                    pause(0.01);
                end
            end

            %% Move back to the initial pose
            if app.Button.Value == 0
                qCurrent = app.robot.model.getpos();
                qTraj = jtraj(qCurrent,zeros(1,7),50);
                for i = 1:50
                    app.robot.model.animate(qTraj(i,:));
                    pause(0.01);
                end

            end

        end

        % Button pushed function: MovekittyButton
        function MovekittyButtonPushed(app, event)
            app.kittyVertexCount = 450;
            [Y,Z] = meshgrid(0:0.1:3,0:0.1:1.8);
            X = repmat(-2,size(Y,1),size(Y,2));
            surf(X,Y,Z,'FaceAlpha',0.1,'EdgeColor','r');
            planeNormal = [-5,1,0];
            planePoint = [-2,1,0];
            checkResult = zeros(100,1);
            steps = 0.016;

            for i = 1:50
                lineStartPoint = [-3.25+steps*i,1,0];
                lineEndPoint = [-2.75+steps*i,1,0];
                lineMidPoint = [-3+steps*i,1,0];
                kittyPose = transl(lineMidPoint)*trotz(pi);
                UpdatedPoints = [kittyPose * [app.vertex,ones(app.kittyVertexCount,1)]']';
                app.kittyMesh_h.Vertices = UpdatedPoints(:,1:3);

                [intersectionPoints,check] = LinePlaneIntersection(planeNormal,planePoint,lineStartPoint,lineEndPoint);

                checkResult(i,1) = check;
                if check == 1
                    disp("COLLISION HAPPEN!")
                end               
                pause(0.02);                
            end
       
        end

        % Button pushed function: SHOOTRIGHTButton
        function SHOOTRIGHTButtonPushed(app, event)
            
            % Start the collision avoidance
            centerpnt = [0.3,0.6,1];
            side = 1.2;
            plotOptions.plotFaces = false;  %Make the cube transparent 
            [vertex,faces,faceNormals] = RectangularPrism(centerpnt-side/2, centerpnt+side/2,plotOptions); %Create the transparent cube   
            q1C= zeros(1,7);                %Initial pose
            q2C = [-2 deg2rad([0 0 0 0 0 -30])];    %Final pose
            qHit = [-1.25 deg2rad([0 0 0 0 0 0])];  %Joint guess
            [qMatrix qWayPoints] = CollisionAvoidance(app.robot,centerpnt,side,q1C,q2C); %IMPORTANT%
            for i = 1:size(qMatrix,1)
                app.robot.model.animate(qMatrix(i,:));
                pause(0.02)
            end
            
            %%
            app.BrickVertexCount = 2588
            %qHit = [2 deg2rad([0 0 0 -90 0 90])];
            velocity = app.InitialvelocitymsEditField.Value;
            g = 9.81;
            theta = deg2rad(app.AngleEditField.Value);
            tEnd = 2*velocity*sin(theta)/g;

            t = linspace(0,tEnd);

            y = velocity*t*cos(theta)-1;
            z = 0.1+velocity*t*sin(theta)-0.5*g*t.^2;
            x = ones(1,100)*(1.5);

            num = 65;
            for i = 1:num
                predict = plot3(x(1,i),y(1,i),z(1,i),'o','MarkerSize',4,'Color','b');
                pause(0.01);
               
            end

            %% Move the end-effector to that point

            T1 = [eye(3) [x(1,num) y(1,num) z(1,num)-0.1]'; zeros(1,3) 1]; %Pose when contact the shuttlecock


            q1 = app.robot.model.ikcon(T1,qHit);                    % Solve for joint angles with intial guess pose


            % Move to swing pose
            qNow = app.robot.model.getpos();
            qTraj = jtraj(qNow,q1,50);
            for i = 1:50
                app.robot.model.animate(qTraj(i,:));
                pause(0.01);
            end
            %% Swing pose by RMRC-Resolved motion rate control => qMatrix

            steps = num;

            x1 = [x(1,num) y(1,num) (z(1,num)-0.1) 0 0 0]';
            x2 = [x(1,num) y(1,num+6) (z(1,num+6)-0.1) 0 0 0]';
            deltaT = 0.05;        % Discrete time step

            xR = zeros(6,steps);    

            s = lspb(0,1,steps);  % Create interpolation scalar
            for i = 1:steps
                xR(:,i) = x1*(1-s(i)) + s(i)*x2;

            end

            qMatrix = [nan(steps,6) zeros(steps,1)];

            qMatrix(1,:) = q1;


            for i = 1:steps-1
                xdot = (xR(:,i+1) - xR(:,i))/deltaT;        % Calculate velocity by taking the derivative
                J = app.robot.model.jacob0(qMatrix(i,:));   % Calculate the Jacobian matrix
                J = J(1:6,1:6);           % Taking the square matrix 6x6      
                qdot = inv(J)*xdot;       % Solve velocitities via RMRC
                                          % Can also use the pinv(J) for
                                          % redundant robot

                qMatrix(i+1,1:4) =  qMatrix(i,1:4) + deltaT*qdot(1:4,1)';  %Only control joint 1 to joint 4

                qMatrix(i+1,5) = deg2rad(-80)-deg2rad(90)/65*i;  %Manually control the link 5 to rotate the racket
                qMatrix(i+1,6) = 0;
                qMatrix(i+1,7) = pi/2;

            end

            counter = num;
            for i=1:num
                %writeDigitalPin(app.a, 'D6', 1);          %Code for the
                %Arduino
                %buttonValue = readDigitalPin(app.a,'D2');
                brick = transl(x(1,i),y(1,i),z(1,i))*trotx(i*pi/100);

                BrickPose = brick ;

                UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);

                app.robot.model.animate(qMatrix(counter,:));

                %T = app.robot.model.fkine(qMatrix(counter,:)).T;


                counter = counter - 1;

                pause(0.01);
                if app.Button.Value == 1 
                    brick = transl([app.xPos,-1.5,0.1]);

                    BrickPose = brick ;

                    UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                    app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);
                    disp("Emergency Button: Triggered!")
                    % writeDigitalPin(app.a, 'D6', 0);
                    % writeDigitalPin(app.a, 'D5', 1);
                    app.EButtonStateLamp.Color = 'r';
                    break
                end
                
            end

            if app.Button.Value == 0 
  
                counter = 1;
                for i=num:-1:1
                    %writeDigitalPin(app.a, 'D6', 1);
                    brick = transl(x(1,i),y(1,i),z(1,i))*trotx(i*pi/100);

                    BrickPose = brick ;

                    UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                    app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);


                    app.robot.model.animate(qMatrix(counter,:));

                    T = app.robot.model.fkine(qMatrix(counter,:)).T;            

                    counter = counter + 1;
                    if app.Button.Value == 1 
                        brick = transl([app.xPos,-1.5,0.1]);

                        BrickPose = brick ;

                        UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';
                    
                        app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);

                        disp("Emergency Button: Triggerd!")

                        %writeDigitalPin(app.a, 'D6', 0);
                        %writeDigitalPin(app.a, 'D5', 1);
                        break
                    end

                    pause(0.01);
                end
            end
            % Move back to the initial pose
            if app.Button.Value == 0 
                qCurrent = app.robot.model.getpos();
                qTraj = jtraj(qCurrent,zeros(1,7),50);
                for i = 1:50
                    app.robot.model.animate(qTraj(i,:));
                    pause(0.01);
                end
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)


            P1 = [-0.5, -2, 1];
            points = [P1]';

            points_h1 = plot3(points(1,1), points(2,1), points(3,1), 'g.', 'MarkerSize', 15);
            cam.plot(points(:,1), 'g.', 'MarkerSize',18);
            cam.hold(true);


            pStar = [cam.pp]';

            deltaT = 1/15;

            lambda = 0.9;
            depth = mean (points(3,:));

            no_plot = true; % decide whether to animate robot or not
            curr_q = app.robot.model.getpos';

            for j = 1:5
                P1 = [-0.25, 0.7, 1.2+0.1*j];
                points = [P1]';
                plot3(points(1,1), points(2,1), points(3,1), 'g.', 'MarkerSize', 15);
                
                % loop of the visual servoing
                ksteps = 0;
                for i = 1:1
                    while true

                        ksteps = ksteps + 1;
                        % compute the view of the camera
                        uv = cam.plot(points(:,i)); % getting the coordinate

                        % cam.plot(points(:,1), 'g.', 'MarkerSize',18);
                        % cam.hold(true);


                        % compute image plane error as a column
                        e = pStar-uv;   % feature error
                        e = e(:);

                        Zest = [];

                        % compute the Jacobian
                        if isempty(depth)
                            % exact depth from simulation (not possible in practice)
                            pt = homtrans(inv(Tcam), P);
                            J = cam.visjac_p(uv, pt(3,:) );
                        elseif ~isempty(Zest)
                            J = cam.visjac_p(uv, Zest);
                        else
                            J = cam.visjac_p(uv, depth );
                        end

                        % compute the velocity of camera in camera frame
                        try
                            v = lambda * pinv(J) * e;
                        catch
                            status = -1;
                            return
                        end
                        

                        %compute robot's Jacobian and inverse
                        J2 = app.robot.model.jacobe(curr_q);
                        Jinv = pinv(J2);
                        % get joint velocities
                        qp = Jinv*v;

                        %Maximum angular velocity cannot exceed 180 degrees/s
                        ind=find(qp>pi);
                        if ~isempty(ind)
                            qp(ind)=pi;
                        end
                        ind=find(qp<-pi);
                        if ~isempty(ind)
                            qp(ind)=-pi;
                        end

                        %Update joints
                        q = curr_q + deltaT*qp;
                        if no_plot ~= true
                            app.robot.model.animate(q');
                        end
                        %Get camera location
                        Tc = app.robot.model.fkine(q');
                        cam.T = Tc;

                        drawnow()
                        app.robot.model.animate(q');


                        % stop if we are not moving the arm on a significant velocity
                        if abs(sum(v)) <= 1e-3 || (ksteps > 100)
                            break;
                        end

                        %update current joint position
                        curr_q = q;
                        % clear camera figure
                        cam.clf;

                    end %loop finishes
                    % disp("Done ! Resuming in 3s...");
                    % disp(strcat("Current error: [",num2str(e(1)'),",",num2str(e(2)'),"] (pixels)"));

                    if no_plot ~= true
                        pause(3);
                    end
                    app.robot.model.animate(q');

                end
            end

            
        end

        % Button pushed function: PrepareforviusalservoingButton
        function PrepareforviusalservoingButtonPushed(app, event)
            q0_alternative = [-0.5 deg2rad([    0,   0,   0,   15,   0, 0])]';
            app.robot.model.animate(q0_alternative');
            
            T0 = app.robot.model.fkine(q0_alternative);
            app.cam.T = T0;                         % mount camera on eef
            % plot camera
            hold on;
            app.cam.plot_camera('pose',T0,'label','scale',0.1);
        end

        % Value changed function: MoveinZdirSpinner
        function MoveinZdirSpinnerValueChanged(app, event)
            value = app.MoveinZdirSpinner.Value;
            P1 = [-0.25+app.MoveinXdirSpinner.Value, 0.7+app.MoveinYdirSpinner.Value, 1.2+value];
            points = [P1]';

            % points_h1 = plot3(points(1,1), points(2,1), points(3,1), 'g.', 'MarkerSize', 15);
            % cam.plot(points(:,1), 'g.', 'MarkerSize',18);
            % cam.hold(true);

            %% Move the racket
            racket = transl(-0.25+app.MoveinXdirSpinner.Value, 0.7+app.MoveinYdirSpinner.Value, 1.2+value);

            RacketPose = racket ;

            UpdatedPoints = [RacketPose * [app.racketVertex,ones(71841,1)]']';

            app.RacketMesh_h.Vertices = UpdatedPoints(:,1:3);
            %%

            pStar = [app.cam.pp]';

            deltaT = 1/15;

            lambda = 0.9;
            depth = mean (points(3,:));

            no_plot = true; % decide whether to animate robot or not
            curr_q = app.robot.model.getpos';

            %for j = 1:5
                %P1 = [-0.25, 0.7, 1.2+0.1*j];
                %points = [P1]';
                plot3(points(1,1), points(2,1), points(3,1), 'b.', 'MarkerSize', 15);

                % loop of the visual servoing
                ksteps = 0;
                for i = 1:1
                    while true

                        ksteps = ksteps + 1;
                        % compute the view of the camera
                        uv = app.cam.plot(points(:,i)); % getting the coordinate

                        % cam.plot(points(:,1), 'g.', 'MarkerSize',18);
                        % cam.hold(true);


                        % compute image plane error as a column
                        e = pStar-uv;   % feature error
                        e = e(:);

                        Zest = [];

                        % compute the Jacobian
                        if isempty(depth)
                            % exact depth from simulation (not possible in practice)
                            pt = homtrans(inv(Tcam), P);
                            J = app.cam.visjac_p(uv, pt(3,:) );
                        elseif ~isempty(Zest)
                            J = app.cam.visjac_p(uv, Zest);
                        else
                            J = app.cam.visjac_p(uv, depth );
                        end

                        % compute the velocity of camera in camera frame
                        try
                            v = lambda * pinv(J) * e;
                        catch
                            status = -1;
                            return
                        end


                        %compute robot's Jacobian and inverse
                        J2 = app.robot.model.jacobe(curr_q);
                        Jinv = pinv(J2);
                        % get joint velocities
                        qp = Jinv*v;

                        %Maximum angular velocity cannot exceed 180 degrees/s
                        ind=find(qp>pi);
                        if ~isempty(ind)
                            qp(ind)=pi;
                        end
                        ind=find(qp<-pi);
                        if ~isempty(ind)
                            qp(ind)=-pi;
                        end

                        %Update joints
                        q = curr_q + deltaT*qp;
                        if no_plot ~= true
                            app.robot.model.animate(q');
                        end
                        %Get camera location
                        Tc = app.robot.model.fkine(q');
                        app.cam.T = Tc;

                        drawnow()
                        app.robot.model.animate(q');


                        % stop if we are not moving the arm on a significant velocity
                        if abs(sum(v)) <= 1e-3 || (ksteps > 100)
                            break;
                        end

                        %update current joint position
                        curr_q = q;
                        % clear camera figure
                        app.cam.clf;

                    end %loop finishes
                    % disp("Done ! Resuming in 3s...");
                    % disp(strcat("Current error: [",num2str(e(1)'),",",num2str(e(2)'),"] (pixels)"));

                    if no_plot ~= true
                        pause(3);
                    end
                    app.robot.model.animate(q');

                %end
            end

        end

        % Value changed function: MoveinXdirSpinner
        function MoveinXdirSpinnerValueChanged(app, event)
            value = app.MoveinXdirSpinner.Value;
            P1 = [-0.25+value, 0.7+app.MoveinYdirSpinner.Value, 1.2+app.MoveinZdirSpinner.Value];
            points = [P1]';

            % points_h1 = plot3(points(1,1), points(2,1), points(3,1), 'g.', 'MarkerSize', 15);
            % cam.plot(points(:,1), 'g.', 'MarkerSize',18);
            % cam.hold(true);

            %% Move the racket
            racket = transl(-0.25+value, 0.7+app.MoveinYdirSpinner.Value, 1.2+app.MoveinZdirSpinner.Value);

            RacketPose = racket ;

            UpdatedPoints = [RacketPose * [app.racketVertex,ones(71841,1)]']';

            app.RacketMesh_h.Vertices = UpdatedPoints(:,1:3);
            pStar = [app.cam.pp]';
            %%
            deltaT = 1/15;

            lambda = 0.9;
            depth = mean (points(3,:));

            no_plot = true; % decide whether to animate robot or not
            curr_q = app.robot.model.getpos';

            %for j = 1:5
            %P1 = [-0.25, 0.7, 1.2+0.1*j];
            %points = [P1]';
            plot3(points(1,1), points(2,1), points(3,1), 'b.', 'MarkerSize', 15);

            % loop of the visual servoing
            ksteps = 0;
            for i = 1:1
                while true

                    ksteps = ksteps + 1;
                    % compute the view of the camera
                    uv = app.cam.plot(points(:,i)); % getting the coordinate

                    % cam.plot(points(:,1), 'g.', 'MarkerSize',18);
                    % cam.hold(true);


                    % compute image plane error as a column
                    e = pStar-uv;   % feature error
                    e = e(:);

                    Zest = [];

                    % compute the Jacobian
                    if isempty(depth)
                        % exact depth from simulation (not possible in practice)
                        pt = homtrans(inv(Tcam), P);
                        J = app.cam.visjac_p(uv, pt(3,:) );
                    elseif ~isempty(Zest)
                        J = app.cam.visjac_p(uv, Zest);
                    else
                        J = app.cam.visjac_p(uv, depth );
                    end

                    % compute the velocity of camera in camera frame
                    try
                        v = lambda * pinv(J) * e;
                    catch
                        status = -1;
                        return
                    end


                    %compute robot's Jacobian and inverse
                    J2 = app.robot.model.jacobe(curr_q);
                    Jinv = pinv(J2);
                    % get joint velocities
                    qp = Jinv*v;

                    %Maximum angular velocity cannot exceed 180 degrees/s
                    ind=find(qp>pi);
                    if ~isempty(ind)
                        qp(ind)=pi;
                    end
                    ind=find(qp<-pi);
                    if ~isempty(ind)
                        qp(ind)=-pi;
                    end

                    %Update joints
                    q = curr_q + deltaT*qp;
                    if no_plot ~= true
                        app.robot.model.animate(q');
                    end
                    %Get camera location
                    Tc = app.robot.model.fkine(q');
                    app.cam.T = Tc;

                    drawnow()
                    app.robot.model.animate(q');


                    % stop if we are not moving the arm on a significant velocity
                    if abs(sum(v)) <= 1e-3 || (ksteps > 100)
                        break;
                    end

                    %update current joint position
                    curr_q = q;
                    % clear camera figure
                    app.cam.clf;

                end %loop finishes
                % disp("Done ! Resuming in 3s...");
                % disp(strcat("Current error: [",num2str(e(1)'),",",num2str(e(2)'),"] (pixels)"));

                if no_plot ~= true
                    pause(3);
                end
                app.robot.model.animate(q');

                %end
            end
        end

        % Value changed function: MoveinYdirSpinner
        function MoveinYdirSpinnerValueChanged(app, event)
            value = app.MoveinYdirSpinner.Value;
            P1 = [-0.25+app.MoveinXdirSpinner.Value, 0.7+value, 1.2+app.MoveinZdirSpinner.Value];
            points = [P1]';

            % points_h1 = plot3(points(1,1), points(2,1), points(3,1), 'g.', 'MarkerSize', 15);
            % cam.plot(points(:,1), 'g.', 'MarkerSize',18);
            % cam.hold(true);

            %% Move the racket
            racket = transl(-0.25+app.MoveinXdirSpinner.Value, 0.7+value, 1.2+app.MoveinZdirSpinner.Value);

            RacketPose = racket ;

            UpdatedPoints = [RacketPose * [app.racketVertex,ones(71841,1)]']';

            app.RacketMesh_h.Vertices = UpdatedPoints(:,1:3);
            pStar = [app.cam.pp]';
            %%
            deltaT = 1/15;

            lambda = 0.9;
            depth = mean (points(3,:));

            no_plot = true; % decide whether to animate robot or not
            curr_q = app.robot.model.getpos';


            plot3(points(1,1), points(2,1), points(3,1), 'b.', 'MarkerSize', 15);

            % loop of the visual servoing
            ksteps = 0;
            for i = 1:1
                while true

                    ksteps = ksteps + 1;
                    % compute the view of the camera
                    uv = app.cam.plot(points(:,i)); % getting the coordinate

                    % cam.plot(points(:,1), 'g.', 'MarkerSize',18);
                    % cam.hold(true);


                    % compute image plane error as a column
                    e = pStar-uv;   % feature error
                    e = e(:);

                    Zest = [];

                    % compute the Jacobian
                    if isempty(depth)
                        % exact depth from simulation (not possible in practice)
                        pt = homtrans(inv(Tcam), P);
                        J = app.cam.visjac_p(uv, pt(3,:) );
                    elseif ~isempty(Zest)
                        J = app.cam.visjac_p(uv, Zest);
                    else
                        J = app.cam.visjac_p(uv, depth );
                    end

                    % compute the velocity of camera in camera frame
                    try
                        v = lambda * pinv(J) * e;
                    catch
                        status = -1;
                        return
                    end


                    %compute robot's Jacobian and inverse
                    J2 = app.robot.model.jacobe(curr_q);
                    Jinv = pinv(J2);
                    % get joint velocities
                    qp = Jinv*v;

                    %Maximum angular velocity cannot exceed 180 degrees/s
                    ind=find(qp>pi);
                    if ~isempty(ind)
                        qp(ind)=pi;
                    end
                    ind=find(qp<-pi);
                    if ~isempty(ind)
                        qp(ind)=-pi;
                    end

                    %Update joints
                    q = curr_q + deltaT*qp;
                    if no_plot ~= true
                        app.robot.model.animate(q');
                    end
                    %Get camera location
                    Tc = app.robot.model.fkine(q');
                    app.cam.T = Tc;

                    drawnow()
                    app.robot.model.animate(q');


                    % stop if we are not moving the arm on a significant velocity
                    if abs(sum(v)) <= 1e-3 || (ksteps > 100)
                        break;
                    end

                    %update current joint position
                    curr_q = q;
                    % clear camera figure
                    app.cam.clf;

                end %loop finishes
                % disp("Done ! Resuming in 3s...");
                % disp(strcat("Current error: [",num2str(e(1)'),",",num2str(e(2)'),"] (pixels)"));

                if no_plot ~= true
                    pause(3);
                end
                app.robot.model.animate(q');

                %end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'background.jpg');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tu-van-chon-vot-cau-long-yonex-nghi-rang.jpg');
            app.MoveButton = uibutton(app.UIFigure, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @MoveButtonValueChanged, true);
            app.MoveButton.Text = 'Move ';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged2, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'stopButton.jpg');
            app.Button.Text = '';
            app.MovetoXYZpostionLabel = uilabel(app.UIFigure);
            app.MovetoXYZpostionLabel.Text = 'Move to X, Y, Z postion ';
            app.RollSliderLabel = uilabel(app.UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.UIFigure);
            app.RollSlider.Limits = [-180 180];
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.Limits = [-180 180];
            app.YawSliderLabel = uilabel(app.UIFigure);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.UIFigure);
            app.YawSlider.Limits = [-180 180];
            app.XmEditFieldLabel = uilabel(app.UIFigure);
            app.XmEditFieldLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmEditFieldLabel = uilabel(app.UIFigure);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.YmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmEditFieldLabel.Text = 'Z (m)';
            app.ZmEditField = uieditfield(app.UIFigure, 'numeric');
            app.q1mSpinnerLabel = uilabel(app.UIFigure);
            app.q1mSpinnerLabel.Text = 'q1 (m)';
            app.q1mSpinner = uispinner(app.UIFigure);
            app.q1mSpinner.Step = 0.1;
            app.q1mSpinner.ValueChangingFcn = createCallbackFcn(app, @q1mSpinnerValueChanging, true);
            app.q1mSpinner.Limits = [-2 0];
            app.q1mSpinner.Value = -0.1;
            app.q2SpinnerLabel = uilabel(app.UIFigure);
            app.q2SpinnerLabel.Text = 'q2 ';
            app.q2Spinner = uispinner(app.UIFigure);
            app.q2Spinner.Step = 5;
            app.q2Spinner.ValueChangingFcn = createCallbackFcn(app, @q2SpinnerValueChanging, true);
            app.q2Spinner.Limits = [-360 360];
            app.q3SpinnerLabel = uilabel(app.UIFigure);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.UIFigure);
            app.q3Spinner.Step = 5;
            app.q3Spinner.ValueChangingFcn = createCallbackFcn(app, @q3SpinnerValueChanging, true);
            app.q3Spinner.Limits = [-60 50];
            app.q4SpinnerLabel = uilabel(app.UIFigure);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.UIFigure);
            app.q4Spinner.Step = 5;
            app.q4Spinner.ValueChangingFcn = createCallbackFcn(app, @q4SpinnerValueChanging, true);
            app.q4Spinner.Limits = [-90 30];
            app.q5SpinnerLabel = uilabel(app.UIFigure);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.UIFigure);
            app.q5Spinner.Step = 5;
            app.q5Spinner.ValueChangingFcn = createCallbackFcn(app, @q5SpinnerValueChanging, true);
            app.q5Spinner.Limits = [-180 60];
            app.q6SpinnerLabel = uilabel(app.UIFigure);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.UIFigure);
            app.q6Spinner.Step = 5;
            app.q6Spinner.ValueChangingFcn = createCallbackFcn(app, @q6SpinnerValueChanging, true);
            app.q6Spinner.Limits = [-90 90];
            app.q7SpinnerLabel = uilabel(app.UIFigure);
            app.q7SpinnerLabel.Text = 'q7';
            app.q7Spinner = uispinner(app.UIFigure);
            app.q7Spinner.Step = 5;
            app.q7Spinner.ValueChangingFcn = createCallbackFcn(app, @q7SpinnerValueChanging, true);
            app.q7Spinner.Limits = [-360 360];
            app.HOMEButton = uibutton(app.UIFigure, 'state');
            app.HOMEButton.ValueChangedFcn = createCallbackFcn(app, @HOMEButtonValueChanged, true);
            app.HOMEButton.Text = 'HOME';
            app.LoadmodeltoCMDTestButton = uibutton(app.UIFigure, 'push');
            app.LoadmodeltoCMDTestButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmodeltoCMDTestButtonPushed, true);
            app.LoadmodeltoCMDTestButton.Text = 'Load model to CMD (Test)';
            app.InitialvelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialvelocitymsEditFieldLabel.Text = 'Initial velocity (m/s)';
            app.InitialvelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialvelocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialvelocitymsEditFieldValueChanged, true);
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle ';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.LeftcourtButton = uibutton(app.UIFigure, 'push');
            app.LeftcourtButton.ButtonPushedFcn = createCallbackFcn(app, @LeftcourtButtonPushed, true);
            app.LeftcourtButton.Text = 'Left court';
            app.MiddlecourtButton = uibutton(app.UIFigure, 'push');
            app.MiddlecourtButton.ButtonPushedFcn = createCallbackFcn(app, @MiddlecourtButtonPushed, true);
            app.MiddlecourtButton.Text = 'Middle court';
            app.RightcourtButton = uibutton(app.UIFigure, 'push');
            app.RightcourtButton.ButtonPushedFcn = createCallbackFcn(app, @RightcourtButtonPushed, true);
            app.RightcourtButton.Text = 'Right court';
            app.PlayingmodeLabel = uilabel(app.UIFigure);
            app.PlayingmodeLabel.Text = 'Playing mode';
            app.SHOOTINGPOSITIONLabel = uilabel(app.UIFigure);
            app.SHOOTINGPOSITIONLabel.Text = 'SHOOTING POSITION';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = '60 (Default)';
            app.DefaultButton_2 = uibutton(app.UIFigure, 'push');
            app.DefaultButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_2Pushed, true);
            app.DefaultButton_2.Text = '6 (Default)';
            app.YESButton = uibutton(app.UIFigure, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Text = 'YES';
            app.RELEASEESTOPLabel = uilabel(app.UIFigure);
            app.RELEASEESTOPLabel.Text = 'RELEASE E-STOP ?';
            app.NOButton = uibutton(app.UIFigure, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Text = 'NO';
            app.EButtonStateLampLabel = uilabel(app.UIFigure);
            app.EButtonStateLampLabel.Text = 'E-Button State';
            app.EButtonStateLamp = uilamp(app.UIFigure);
            app.SHOOTMIDButton = uibutton(app.UIFigure, 'push');
            app.SHOOTMIDButton.ButtonPushedFcn = createCallbackFcn(app, @SHOOTMIDButtonPushed, true);
            app.SHOOTMIDButton.Text = 'SHOOT MID';
            app.ClickSHOOTtomakethemovementLabel = uilabel(app.UIFigure);
            app.ClickSHOOTtomakethemovementLabel.Text = 'Click "SHOOT" to make the movement';
            app.CURRENTXmEditFieldLabel = uilabel(app.UIFigure);
            app.CURRENTXmEditFieldLabel.Text = 'CURRENT X (m)';
            app.CURRENTXmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CURRENTYmEditFieldLabel = uilabel(app.UIFigure);
            app.CURRENTYmEditFieldLabel.Text = 'CURRENT Y  (m)';
            app.CURRENTYmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CURRENTZmEditFieldLabel = uilabel(app.UIFigure);
            app.CURRENTZmEditFieldLabel.Text = 'CURRENT Z  (m)';
            app.CURRENTZmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MOVERPYButton = uibutton(app.UIFigure, 'push');
            app.MOVERPYButton.ButtonPushedFcn = createCallbackFcn(app, @MOVERPYButtonPushed, true);
            app.MOVERPYButton.Text = 'MOVE RPY';
            app.SHOOTLEFTButton = uibutton(app.UIFigure, 'push');
            app.SHOOTLEFTButton.ButtonPushedFcn = createCallbackFcn(app, @SHOOTLEFTButtonPushed, true);
            app.SHOOTLEFTButton.Text = 'SHOOT LEFT';
            app.SHOOTRIGHTButton = uibutton(app.UIFigure, 'push');
            app.SHOOTRIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @SHOOTRIGHTButtonPushed, true);
            app.SHOOTRIGHTButton.Text = 'SHOOT RIGHT';
            app.MovekittyButton = uibutton(app.UIFigure, 'push');
            app.MovekittyButton.ButtonPushedFcn = createCallbackFcn(app, @MovekittyButtonPushed, true);
            app.MovekittyButton.Text = 'Move kitty';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.PrepareforviusalservoingButton = uibutton(app.UIFigure, 'push');
            app.PrepareforviusalservoingButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareforviusalservoingButtonPushed, true);
            app.PrepareforviusalservoingButton.Text = 'Prepare for viusal servoing';
            app.MoveinZdirSpinnerLabel = uilabel(app.UIFigure);
            app.MoveinZdirSpinnerLabel.Text = 'Move in Z dir';
            app.MoveinZdirSpinner = uispinner(app.UIFigure);
            app.MoveinZdirSpinner.Step = 0.05;
            app.MoveinZdirSpinner.Limits = [-2 2];
            app.MoveinZdirSpinner.ValueChangedFcn = createCallbackFcn(app, @MoveinZdirSpinnerValueChanged, true);
            app.MoveinXdirSpinnerLabel = uilabel(app.UIFigure);
            app.MoveinXdirSpinnerLabel.Text = 'Move in X dir';
            app.MoveinXdirSpinner = uispinner(app.UIFigure);
            app.MoveinXdirSpinner.Step = 0.05;
            app.MoveinXdirSpinner.Limits = [-2 2];
            app.MoveinXdirSpinner.ValueChangedFcn = createCallbackFcn(app, @MoveinXdirSpinnerValueChanged, true);
            app.MoveinYdirSpinnerLabel = uilabel(app.UIFigure);
            app.MoveinYdirSpinnerLabel.Text = 'Move in Y dir';
            app.MoveinYdirSpinner = uispinner(app.UIFigure);
            app.MoveinYdirSpinner.Step = 0.05;
            app.MoveinYdirSpinner.Limits = [-2 2];
            app.MoveinYdirSpinner.ValueChangedFcn = createCallbackFcn(app, @MoveinYdirSpinnerValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end