classdef PlayingMode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChoosetheshootingpostionLabel  matlab.ui.control.Label
        RightcourtButton               matlab.ui.control.Button
        MiddlecourtButton              matlab.ui.control.Button
        LeftcourtButton                matlab.ui.control.Button
        PlayingmodeLabel               matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        InitialvelocitymsEditField     matlab.ui.control.NumericEditField
        InitialvelocitymsLabel         matlab.ui.control.Label
        SaveandcloseButton             matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        xPos double
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        end

        % Button pushed function: SaveandcloseButton
        function SaveandcloseButtonPushed(app, event)
            assignin("base","velocity",app.InitialvelocitymsEditField.Value);
            assignin("base","angle",app.AngleEditField.Value);
            assignin("base","xPos",app.xPos);
                       
            %app.delete
        end

        % Callback function
        function UIAxesButtonDown(app, event)
          
        end

        % Button pushed function: LeftcourtButton
        function LeftcourtButtonPushed(app, event)
            app.xPos = -1.25;
        end

        % Button pushed function: MiddlecourtButton
        function MiddlecourtButtonPushed(app, event)
            app.xPos = 0;
        end

        % Button pushed function: RightcourtButton
        function RightcourtButtonPushed(app, event)
            app.xPos = 1.25;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'background.jpg');
            app.SaveandcloseButton = uibutton(app.UIFigure, 'push');
            app.SaveandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandcloseButtonPushed, true);
            app.SaveandcloseButton.Text = 'Save and close';
            app.InitialvelocitymsLabel = uilabel(app.UIFigure);
            app.InitialvelocitymsLabel.Text = 'Initial velocity (m/s)';
            app.InitialvelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle ';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlayingmodeLabel = uilabel(app.UIFigure);
            app.PlayingmodeLabel.Text = 'Playing mode';
            app.LeftcourtButton = uibutton(app.UIFigure, 'push');
            app.LeftcourtButton.ButtonPushedFcn = createCallbackFcn(app, @LeftcourtButtonPushed, true);
            app.LeftcourtButton.Text = 'Left court';
            app.MiddlecourtButton = uibutton(app.UIFigure, 'push');
            app.MiddlecourtButton.ButtonPushedFcn = createCallbackFcn(app, @MiddlecourtButtonPushed, true);
            app.MiddlecourtButton.Text = 'Middle court';
            app.RightcourtButton = uibutton(app.UIFigure, 'push');
            app.RightcourtButton.ButtonPushedFcn = createCallbackFcn(app, @RightcourtButtonPushed, true);
            app.RightcourtButton.Text = 'Right court';
            app.ChoosetheshootingpostionLabel = uilabel(app.UIFigure);
            app.ChoosetheshootingpostionLabel.Text = 'Choose the shooting postion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlayingMode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end